classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DownloadImageButton     matlab.ui.control.Button
        EditEvaluationButton    matlab.ui.control.Button
        FinishEditButton        matlab.ui.control.Button
        Evaluation              matlab.ui.control.TextArea
        UploadImageButton_2     matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        UploadImageButton       matlab.ui.control.Button
        ChangeImageButton       matlab.ui.control.Button
        ProceedButton           matlab.ui.control.Button
        ImageProcessed          matlab.ui.control.Image
        ImageRaw                matlab.ui.control.Image
        ImageNoiseReduced       matlab.ui.control.Image
        ImageAfterSegmentation  matlab.ui.control.Image
    end

    % Image Processing Code

    properties (Access = private)
        RawImageData %Raw Image
        DenoisedData %Denoised Image
        BinaryMask   %Region of Interest Identifier
        FinalOutput  %Final Image 
        BorderMask
    end

   % --- Tunable pipeline settings --- %
   properties (Access = private)
       RequireHoughMatch = false;
       HoughFallbackCircularity = 0.65;
       DebugMode = true;
       DebugLog = {};
   end 


methods (Access = private)
        % --- Parameter Estimation Stage --- %

        function logDebug(app,msg)
            fprintf('%s\n', msg);
            app.DebugLog{end+1} = msg;
        end
        function nIters = autoEstimateDenoiseStrength(~, img)
            noiseVar = max(0, mean2(stdfilt(img).^2));
            nIters = round(min(12, max(3, noiseVar * 50)));
        end
 
        function sensitivity = autoEstimateSensitivity(~, img, roiMask)
            roiPixels = img(roiMask);
            localStd = std(roiPixels);
            sensitivity = min(0.60, max(0.35, 0.55 - localStd * 0.6));
        end
 
        function nb = autoEstimateNeighborhoodSize(~, img)
            [rows, cols] = size(img);
            nb = round(min(rows, cols) * 0.15);
            if mod(nb, 2) == 0
                nb = nb + 1;
            end
            nb = max(nb, 15);
        end
        function [minArea, maxArea] = autoEstimateMorphParams(~, img)
            [numRows, numCols] = size(img);
            totalPixels = numRows * numCols;
            minArea = max(120, round(totalPixels * 0.0015)); 
            maxArea = round(totalPixels * 0.08); 
        end
        function tissueROI = extractOvarianROI(~, img)
            [rows, cols] = size(img);
            tissueMask = img > 0.08; 
            
            tissueMask = imclose(tissueMask, strel('disk', 15));
            tissueMask = imfill(tissueMask, 'holes');
            tissueMask = imopen(tissueMask, strel('disk', 10));
            
            % Gentle vertical erosion
            tissueROI = imerode(tissueMask, strel('disk', 8));
            
            % INCREASED LEFT CROP: Slice out the far-left acoustic frame artifact
            sideCrop = false(rows, cols);
            sideCrop(:, round(cols * 0.20):round(cols * 0.92)) = true;
            tissueROI = tissueROI & sideCrop;
        end        
        % --- Core Processing Functions ---
        function denoisedImg = runDenoisingStage(~, inputImg, filterStrength)
            denoisedImg = imdiffusefilt(inputImg, ...
                'GradientThreshold', 0.1, ...
                'NumberOfIterations', filterStrength);
        end
 
        function segMask = runSegmentationStage(app, denoisedImg, sensitivity, ~)
            ovaryROI = app.extractOvarianROI(denoisedImg) & app.BorderMask;
        
            tissuePixels = denoisedImg(ovaryROI);
            if isempty(tissuePixels)
                segMask = false(size(denoisedImg));
                return;
            end
        
            level = multithresh(tissuePixels, 1);
            darkCutoff = level * (1.0 - sensitivity * 0.25);
            segMask = (denoisedImg < darkCutoff) & ovaryROI;
        
            if app.DebugMode
                preClearCount = max(bwlabel(segMask), [], 'all');
                app.logDebug(sprintf('--- runSegmentationStage: %d raw dark region(s) segmented ---', preClearCount));
            end
        end
 
function cleanMask = executeRobustSegmentation(app, denoisedImg)
            % 1. Create ROI
            roiMask = app.extractOvarianROI(denoisedImg) & app.BorderMask;
        
            % 2. Detect MSER dark candidates
            [mserRegions, ~] = detectMSERFeatures(denoisedImg, ...
                'RegionAreaRange', [30 12000], ...
                'ThresholdDelta', 1.2, ...
                'MaxAreaVariation', 0.30);
        
            if isempty(mserRegions)
                cleanMask = false(size(denoisedImg));
                if app.DebugMode
                    app.logDebug('--- executeRobustSegmentation: 0 MSER seeds found ---');
                end
                return;
            end
        
            % 3. Convert MSER seeds into binary mask
            rawSeedMask = false(size(denoisedImg));
            for i = 1:mserRegions.Count
                pixelList = mserRegions.PixelList{i};
                idx = sub2ind(size(denoisedImg), pixelList(:,2), pixelList(:,1));
                rawSeedMask(idx) = true;
            end
            rawSeedMask = rawSeedMask & roiMask;
        
            % 4. Active Contour Refinement (expanded iterations to capture boundary edges)
            refinedMask = activecontour(denoisedImg, rawSeedMask, 15, 'Chan-Vese', ...
                'SmoothFactor', 0.7, 'ContractionBias', 0.2);
            
            refinedMask = refinedMask & roiMask;
        
            % 5. Watershed splitting with lower tolerance to prevent clipping small follicles
            D = -bwdist(~refinedMask);
            D_mod = imimposemin(D, imextendedmin(D, 0.7));
            splitL = watershed(D_mod);
            refinedMask(splitL == 0) = false;
            refinedMask = bwareaopen(refinedMask, 35);
        
            % 6. Dynamic Intensity Cap
            roiPixels = denoisedImg(roiMask);
            if ~isempty(roiPixels)
                roiContrast = std(roiPixels);
                if roiContrast > 0.06
                    dynamicDarkCap = max(0.21, quantile(roiPixels, 0.40));
                else
                    dynamicDarkCap = 0.11;
                end
            else
                dynamicDarkCap = 0.22;
            end
        
            % 7. Feature Extraction & Gating
            stats = regionprops(refinedMask, 'Area', 'Perimeter', 'Solidity', ...
                'Eccentricity', 'Extent', 'Centroid', 'PixelIdxList');
            
            validIndices = [];
            
            if app.DebugMode
                app.logDebug(sprintf('--- executeRobustSegmentation: %d candidate blob(s) evaluated ---', length(stats)));
            end
        
            [imgRows, imgCols] = size(denoisedImg);
            for k = 1:length(stats)
                area = stats(k).Area;
                perim = stats(k).Perimeter;
                solidity = stats(k).Solidity;
                eccentricity = stats(k).Eccentricity;
                extent = stats(k).Extent;
                centroid = stats(k).Centroid;
        
                if perim == 0, continue; end
                circularity = (4 * pi * area) / (perim^2);
        
                regionPixels = denoisedImg(stats(k).PixelIdxList);
                meanIntensity = mean(regionPixels);
        
                % --- SPATIAL ARTIFACT GATES ---
                isFarLeft    = centroid(1) < (imgCols * 0.20);
                isLowerRight = centroid(2) > (imgRows * 0.85) && centroid(1) > (imgCols * 0.65);
                
                % --- Acceptance Gates ---
                passArea = area >= 30 && area <= 12000;
                
                if isFarLeft || isLowerRight
                    passSolidity    = solidity > 0.90;
                    passCircularity = circularity > 0.60;
                else
                    passSolidity    = solidity > 0.50;
                    passCircularity = circularity > 0.12;
                end
                
                passEccentricity = eccentricity < 0.96;
                passExtent       = extent < 0.92;
                passMeanInt      = meanIntensity <= dynamicDarkCap;
        
                isValid = passArea && passSolidity && passCircularity && ...
                          passEccentricity && passExtent && passMeanInt && ~isFarLeft;
        
                if app.DebugMode
                    app.logDebug(sprintf(['blob %2d | area=%5d circ=%.2f solid=%.2f ', ...
                        'meanI=%.2f (cap=%.2f) -> %s'], k, area, circularity, solidity, ...
                        meanIntensity, dynamicDarkCap, string(isValid)));
                end
        
                if isValid
                    validIndices = [validIndices; k]; %#ok<AGROW>
                end
            end
        
            labeled = bwlabel(refinedMask);
            cleanMask = ismember(labeled, validIndices);
            
            if app.DebugMode
                app.logDebug(sprintf('--- %d of %d blob(s) accepted ---', length(validIndices), length(stats)));
            end
        end

        % --- Rendering & Display Handler ---
        function renderVisualResults(app)
            % Mask final output
            app.FinalOutput = app.DenoisedData;
            app.FinalOutput(~app.BinaryMask) = 0;
 
            % Assign component sources
            app.ImageRaw.ImageSource = app.matrixToImageSource(app.RawImageData);
            app.ImageNoiseReduced.ImageSource = app.matrixToImageSource(app.DenoisedData);
            app.ImageAfterSegmentation.ImageSource = app.matrixToImageSource(double(app.BinaryMask));
 
            % Generate boundary overlay
            overlayImg = app.createBoundaryOverlay(app.DenoisedData, app.BinaryMask);
            app.ImageProcessed.ImageSource = app.matrixToImageSource(overlayImg);
        end
 
        % --- Master Pipeline Execution ---
        function executeAutoPipeline(app)
            if isempty(app.RawImageData)
                return;
            end
        
            app.DebugLog = {}; % reset debug log for this run
        
            autoDenoiseIter = app.autoEstimateDenoiseStrength(app.RawImageData);
            autoSens        = app.autoEstimateSensitivity(app.RawImageData, app.BorderMask);
            autoNeighborhood = app.autoEstimateNeighborhoodSize(app.RawImageData); 
            app.DenoisedData = app.runDenoisingStage(app.RawImageData, autoDenoiseIter);
            app.BinaryMask   = app.runSegmentationStage(app.DenoisedData, autoSens, autoNeighborhood);
            app.BinaryMask   = app.executeRobustSegmentation(app.DenoisedData);
            
            if app.DebugMode && ~isempty(app.DebugLog)
                app.Evaluation.Value = app.DebugLog(:);
            end
            app.renderVisualResults();
        end
 
        function normImg = prepareRawImage(app, rawMatrix)
            if size(rawMatrix, 3) == 3
                rawMatrix = rgb2gray(rawMatrix);
            end
            normImg = im2double(rawMatrix);
            [rows, cols] = size(normImg);
 
            borderMask = false(rows, cols);
            borderMask(round(rows*0.05):round(rows*0.95), round(cols*0.05):round(cols*0.95)) = true;
            app.BorderMask = borderMask;
        end
 
        function imgSource = matrixToImageSource(~, imgMatrix)
            tempPath = [tempname, '.png'];
            imwrite(imgMatrix, tempPath);
            imgSource = tempPath;
        end
 
        function rgbOverlay = createBoundaryOverlay(~, baseImg, mask)
            rgbOverlay = repmat(baseImg, [1, 1, 3]);
            boundaries = bwperim(mask);
 
            rChannel = rgbOverlay(:,:,1); rChannel(boundaries) = 1.0; rgbOverlay(:,:,1) = rChannel;
            gChannel = rgbOverlay(:,:,2); gChannel(boundaries) = 1.0; rgbOverlay(:,:,2) = gChannel;
            bChannel = rgbOverlay(:,:,3); bChannel(boundaries) = 0.0; rgbOverlay(:,:,3) = bChannel;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChangeImageButton, UploadImageButton, 
        % ...and 1 other component
        function UploadImageButtonPushed(app, event)
        % File Upload & Configure acceptable graphic extensions
            fileFilters = {'*.png;*.jpg;*.jpeg;*.bmp;*.tif', 'Image Files (*.png, *.jpg, *.jpeg, *.bmp, *.tif)'};
    
            % Open user navigation window
            [filename, pathname] = uigetfile(fileFilters, 'Select an Image');
    
            % Verify a valid file was selected
            if ~isequal(filename,0) && ~isequal(pathname,0)
                fullFilePath = fullfile(pathname, filename);
                
                % Store file path in primary UI Image component
                app.Image.ImageSource = fullFilePath;
                
                % Read raw image matrix and normalize for backend processing
                rawMatrix = imread(fullFilePath);
                app.RawImageData = app.prepareRawImage(rawMatrix);
                
                % Update visibility
                app.UploadImageButton.Visible = 'off';
                app.ChangeImageButton.Visible = 'on';
                app.ProceedButton.Visible = 'on';
                app.ImageProcessed.Visible = 'off';
                app.ImageRaw.Visible = 'off';
                app.ImageNoiseReduced.Visible = 'off';
                app.ImageAfterSegmentation.Visible = 'off';
                app.UploadImageButton_2.Visible = 'off';
                app.EditEvaluationButton.Visible = 'off';
                app.DownloadImageButton.Visible = 'off';
                app.Image.Visible = 'on'
                app.Evaluation.Visible= 'off';
            end
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            % Hide Previous Buttons and Main Preview Image
            app.ProceedButton.Visible = 'off';
            app.ChangeImageButton.Visible = 'off';
            app.Image.Visible = 'off';
            
            % Show Processing UI components
            app.ImageProcessed.Visible = 'on';
            app.ImageRaw.Visible = 'on';
            app.ImageNoiseReduced.Visible = 'on';
            app.ImageAfterSegmentation.Visible = 'on';
            app.UploadImageButton_2.Visible = 'on';
            app.EditEvaluationButton.Visible = 'on';
            app.Evaluation.Visible = 'on';
            app.DownloadImageButton.Visible = 'on';

            % Execute the automated processing pipeline
            app.executeAutoPipeline();
        end

        % Button pushed function: EditEvaluationButton
        function EditEvaluationButtonPushed(app, event)
            app.Evaluation.Editable = 'on';
            app.FinishEditButton.Visible = 'on';
            app.EditEvaluationButton.Visible = 'off';
        end

        % Button pushed function: FinishEditButton
        function FinishEditButtonButtonPushed(app, event)
            app.Evaluation.Editable = 'off';
            app.FinishEditButton.Visible = 'off';
            app.EditEvaluationButton.Visible = 'on';
        end

        % Image clicked function: ImageRaw
        function ImageRawClicked(app, event)
            tempSource1 = app.ImageProcessed.ImageSource;
            app.ImageProcessed.ImageSource = app.ImageRaw.ImageSource;
            app.ImageRaw.ImageSource = tempSource1;
        end

        % Image clicked function: ImageNoiseReduced
        function ImageNoiseReducedClicked(app, event)
            tempSource2 = app.ImageProcessed.ImageSource;
            app.ImageProcessed.ImageSource = app.ImageNoiseReduced.ImageSource;
            app.ImageNoiseReduced.ImageSource = tempSource2;
        end

        % Image clicked function: ImageAfterSegmentation
        function ImageAfterSegmentationClicked(app, event)
            tempSource3 = app.ImageProcessed.ImageSource;
            app.ImageProcessed.ImageSource = app.ImageAfterSegmentation.ImageSource;
            app.ImageAfterSegmentation.ImageSource = tempSource3;
        end

        % Button pushed function: DownloadImageButton
        function DownloadImageButtonPushed(app, event)
            currentPath = app.ImageProcessed.ImageSource;

            [filename, filepath] = uiputfile({'*.png', 'PNG Image (*.png)'; ...
                '*.jpg', 'JPEG Image (*.jpg)'}, ...
                'Save Image As');

            if ischar(filename) || isstring(filename)
                destinationPath = fullfile(filepath, filename);
                copyfile(currentPath, destinationPath);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAfterSegmentation = uiimage(app.UIFigure);
            app.ImageAfterSegmentation.ImageClickedFcn = createCallbackFcn(app, @ImageAfterSegmentationClicked, true);
            app.ImageAfterSegmentation.Visible = 'off';
            app.ImageNoiseReduced = uiimage(app.UIFigure);
            app.ImageNoiseReduced.ImageClickedFcn = createCallbackFcn(app, @ImageNoiseReducedClicked, true);
            app.ImageNoiseReduced.Visible = 'off';
            app.ImageRaw = uiimage(app.UIFigure);
            app.ImageRaw.ImageClickedFcn = createCallbackFcn(app, @ImageRawClicked, true);
            app.ImageRaw.Visible = 'off';
            app.ImageProcessed = uiimage(app.UIFigure);
            app.ImageProcessed.Visible = 'off';
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Visible = 'off';
            app.ProceedButton.Text = 'Proceed';
            app.ChangeImageButton = uibutton(app.UIFigure, 'push');
            app.ChangeImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.ChangeImageButton.Visible = 'off';
            app.ChangeImageButton.Text = 'Change Image';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.Image = uiimage(app.UIFigure);
            app.UploadImageButton_2 = uibutton(app.UIFigure, 'push');
            app.UploadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton_2.Visible = 'off';
            app.UploadImageButton_2.Text = 'Upload Image';
            app.Evaluation = uitextarea(app.UIFigure);
            app.Evaluation.Editable = 'off';
            app.Evaluation.Visible = 'off';
            app.FinishEditButton = uibutton(app.UIFigure, 'push');
            app.FinishEditButton.ButtonPushedFcn = createCallbackFcn(app, @FinishEditButtonButtonPushed, true);
            app.FinishEditButton.Visible = 'off';
            app.FinishEditButton.Text = 'Finish Edit';
            app.EditEvaluationButton = uibutton(app.UIFigure, 'push');
            app.EditEvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @EditEvaluationButtonPushed, true);
            app.EditEvaluationButton.Visible = 'off';
            app.EditEvaluationButton.Text = 'Edit Evaluation';
            app.DownloadImageButton = uibutton(app.UIFigure, 'push');
            app.DownloadImageButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadImageButtonPushed, true);
            app.DownloadImageButton.Visible = 'off';
            app.DownloadImageButton.Text = 'Download Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end